classdef SoapboxTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoapboxioUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcomeLabel                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel  matlab.ui.control.Label
        SoapboxioLabel                  matlab.ui.control.Label
        Releasev005May2021Label         matlab.ui.control.Label
        AsoftwaretoolforsoapboxderbypreracetuningoptimizationLabel  matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        NewinthisupdateLabel            matlab.ui.control.Label
        AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_2  matlab.ui.control.Label
        AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_3  matlab.ui.control.Label
        PresstheDatatabtogetstartedLabel  matlab.ui.control.Label
        DataTab                         matlab.ui.container.Tab
        CarPanel                        matlab.ui.container.Panel
        ftLabel                         matlab.ui.control.Label
        FrontaxledistanceEditFieldLabel  matlab.ui.control.Label
        LaEdit                          matlab.ui.control.NumericEditField
        ftLabel_2                       matlab.ui.control.Label
        RearaxledistanceEditFieldLabel  matlab.ui.control.Label
        LbEdit                          matlab.ui.control.NumericEditField
        ftLabel_5                       matlab.ui.control.Label
        RadiusofwheelEditFieldLabel     matlab.ui.control.Label
        RwEdit                          matlab.ui.control.NumericEditField
        lbLabel_4                       matlab.ui.control.Label
        WeightofwheelEditFieldLabel     matlab.ui.control.Label
        MwEdit                          matlab.ui.control.NumericEditField
        ftLabel_3                       matlab.ui.control.Label
        DriverseatdistanceLabel         matlab.ui.control.Label
        LdEdit                          matlab.ui.control.NumericEditField
        ftLabel_4                       matlab.ui.control.Label
        OveralllengthLabel              matlab.ui.control.Label
        LtEdit                          matlab.ui.control.NumericEditField
        mwLabel                         matlab.ui.control.Label
        rwLabel                         matlab.ui.control.Label
        LtLabel                         matlab.ui.control.Label
        LdLabel                         matlab.ui.control.Label
        LbLabel                         matlab.ui.control.Label
        LaLabel                         matlab.ui.control.Label
        CardimensionsLabel              matlab.ui.control.Label
        RequiredPanel                   matlab.ui.container.Panel
        lbLabel_5                       matlab.ui.control.Label
        WeightofdriverEditField_3Label  matlab.ui.control.Label
        WdEdit                          matlab.ui.control.NumericEditField
        lbLabel_6                       matlab.ui.control.Label
        WeightofemptycarnodriverLabel   matlab.ui.control.Label
        WcEdit                          matlab.ui.control.NumericEditField
        HillProfileButton               matlab.ui.control.Button
        HillprofiledataLabel_2          matlab.ui.control.Label
        MaximumraceweightofcarLabel     matlab.ui.control.Label
        WmaxEdit                        matlab.ui.control.NumericEditField
        lbLabel_7                       matlab.ui.control.Label
        TotalmoveableweightLabel        matlab.ui.control.Label
        WmoveEdit                       matlab.ui.control.NumericEditField
        lbLabel_8                       matlab.ui.control.Label
        HillProfileStatus               matlab.ui.control.Label
        RoadLoadButton                  matlab.ui.control.Button
        RoadloaddataLabel               matlab.ui.control.Label
        RoadLoadStatus                  matlab.ui.control.Label
        RaceConditionsLabel             matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        AxlePanel                       matlab.ui.container.Panel
        lbLabel_9                       matlab.ui.control.Label
        WeightofaxleLabel               matlab.ui.control.Label
        MaEdit                          matlab.ui.control.NumericEditField
        inLabel_5                       matlab.ui.control.Label
        LengthofaxleLabel               matlab.ui.control.Label
        LaxEdit                         matlab.ui.control.NumericEditField
        inLabel_2                       matlab.ui.control.Label
        AxlethicknessLabel              matlab.ui.control.Label
        AtEdit                          matlab.ui.control.NumericEditField
        inLabel_3                       matlab.ui.control.Label
        LengthofspindleLabel            matlab.ui.control.Label
        LsEdit                          matlab.ui.control.NumericEditField
        inLabel_4                       matlab.ui.control.Label
        SpindlethicknessLabel           matlab.ui.control.Label
        StEdit                          matlab.ui.control.NumericEditField
        AtLabel                         matlab.ui.control.Label
        StLabel                         matlab.ui.control.Label
        LsLabel                         matlab.ui.control.Label
        LaxLabel                        matlab.ui.control.Label
        maLabel                         matlab.ui.control.Label
        AxledimensionsLabel             matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        SimulationTab                   matlab.ui.container.Tab
        SimResultsPanel                 matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        xcgGauge                        matlab.ui.control.LinearGauge
        TailheavyLampLabel              matlab.ui.control.Label
        TailheavyLamp                   matlab.ui.control.Lamp
        NoseheavyLampLabel              matlab.ui.control.Label
        NoseheavyLamp                   matlab.ui.control.Lamp
        poundLabel                      matlab.ui.control.Label
        cgSymbol                        matlab.ui.control.Image
        poundLabel_2                    matlab.ui.control.Label
        poundLabel_3                    matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        StatusPanel                     matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabelTitle                matlab.ui.control.Label
        HillProfileFigure               matlab.ui.control.UIAxes
        RaceTimeFigure                  matlab.ui.control.UIAxes
        RoadLoadFigure                  matlab.ui.control.UIAxes
        SpindlesTab                     matlab.ui.container.Tab
        FrontAxlePanel                  matlab.ui.container.Panel
        Image6                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        FrontAxleFigure                 matlab.ui.control.UIAxes
        AverageforcesLabel              matlab.ui.control.Label
        AxLabel                         matlab.ui.control.Label
        AyLabel                         matlab.ui.control.Label
        CamberyLabel                    matlab.ui.control.Label
        CamberThetaLabel                matlab.ui.control.Label
        CamberDeltaLabel                matlab.ui.control.Label
        ToexLabel                       matlab.ui.control.Label
        ToeThetaLabel                   matlab.ui.control.Label
        ToeDeltaLabel                   matlab.ui.control.Label
        FrontaxleLabel                  matlab.ui.control.Label
        RearAxlePanel                   matlab.ui.container.Panel
        Image6_2                        matlab.ui.control.Image
        Image7_2                        matlab.ui.control.Image
        RearAxleFigure                  matlab.ui.control.UIAxes
        AverageforcesLabel_2            matlab.ui.control.Label
        BxLabel                         matlab.ui.control.Label
        ByLabel                         matlab.ui.control.Label
        CamberyLabel_2                  matlab.ui.control.Label
        CamberThetaLabel2               matlab.ui.control.Label
        CamberDeltaLabel2               matlab.ui.control.Label
        ToexLabel_2                     matlab.ui.control.Label
        ToeThetaLabel2                  matlab.ui.control.Label
        ToeDeltaLabel2                  matlab.ui.control.Label
        RearaxleLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        % convert lb to kg
        lb_kg = 0.453592;
        
        % Set default parameters
        La = 6/12;              % front axle distance [ft]
        Lb = 65.5/12;           % rear axle distance [ft]
        Ld = 38.5/12;           % driver seat distance [ft]
        Lt = 76.5/12;           % total car length [ft]
        Rw = 6/12;              % wheel radius [ft]
        Mw = 2.31;              % mass of wheel [lbm] 
        
        Ma = 4.77;                 % mass of axle [lbm]
        Lax = 34.75;                % Length of axle [in]
        Ls = 3.25;                 % Length of spindle [in]
        St = 0.5;                 % Spindle thickness [in] -- circular cross-section
        At = 0.75;                 % Axle thickness [in] -- square cross-section
        
        Wd = 80;                % Weight of driver [lbf]
        Wc = 70;                % Weight of car (empty) [lbf]
        Wmove = 50;             % Weight of moveable weights [lbf]
        Wmax = 240;             % Max weight of car (with driver and free weights) [lbf]
        
        dfMax = 15;             % Max tail-heavy/nose-heavy [lbf]
        Crr = 0.02;            % coefficient of rolling resistance (typical)
        E = 30.5*10^6           % Young's modulus of steel [psi]
        
        errorColor = [1 0 0];
        warnColor = [1 1 0];
        successColor = [0 1 0];
        optionalColor = [1 1 0.9];
        defaultColor = [0.94 0.94 0.94];
        
        greenTextColor = [0 0.8 0];
        
        hillDataFile = "Hello";
        roadDataFile = "Goodbye";
        slopeData = [];
        roadLoadData = [];
        
        roadLoadUploaded = false;
        hillProfileUploaded = false;
        
        p_coeff = []; % road load fit coefficients (2nd-order)
        
        % special characters
        theta = char(952);
        delta = char(0x3B4);
    end
    
    methods (Access = private)
        
        % Use sum of straight line approximations for hill profile
        function [hill_points, thetaq] = makeHillProfile(app)
            distance = app.slopeData(:,1);
            slope = app.slopeData(:,2)*(pi/180); % rad
            segments = diff(distance);
            dy = zeros(length(segments),1);
            dx = zeros(length(segments),1);
            
            % distance(1) = 0; don't use slope(end)
            dy = segments .* sin(slope(1:end-1,1));
            dx = segments .* cos(slope(1:end-1,1));
            
            H = abs(sum(dy));      % Y height
            
            % X,Y hill profile
            x = zeros(1,length(slope));
            y = zeros(1,length(slope));
            
            x(1) = 0;
            y(1) = H;
            
            for i = 2:length(distance)
                x(i) = x(i-1) + dx(i-1);
                y(i) = y(i-1) + dy(i-1);
            end
            
            % Add extra point at back of car starting line
            car_length = app.Lb - app.La; % axle to axle distance
            initial_slope = slope(1);
            y_initial = y(1) + car_length * sin(abs(initial_slope));
            x_initial = 0;
            distance = distance + car_length;
            x = x + car_length * cos(initial_slope);
            distance = [0; distance];
            x = [x_initial, x];
            y = [y_initial, y];
            slope = [initial_slope; slope];
            
            N = 1000; % number of interpolation points
            xq = linspace(x(1),x(end),N);
            yq = interp1(x,y,xq,'spline');
            sq = interp1(x,distance,xq,'linear');
            thetaq = interp1(x,slope,xq,'pchip')';
            
            hill_points = [xq' yq' sq']; % x,y,s matrix
            set(app.HillProfileFigure,'YLim',[0 2*yq(1)]);
            set(app.HillProfileFigure,'XLim',[0 xq(end)]);
            
        end
        
        % Return amount of weight to go tail-heavy or nose-heavy (lb)
        % Negative = nose-heavy, positive = tail-heavy
        % Arg: xcg = desired center of gravity (measured from nose)
        function df = get_weight_dist(app,xcg)
            a = xcg - app.La; % [ft]
            b = app.Lb - xcg;
            Fa = (app.Wmax * b) / (a + b); % force on front axle
            Fb = (app.Wmax * a) / (a + b); % force on rear axle
            df = Fb - Fa;
        end
        
        
        function [t_vec, v_mat] = rk4(app,dv_fcn, v0_vec, dt, track_end)
            n = size(v0_vec,1);     % n = number of state variables
            t_vec = [0];            % time column vector
            v_mat = zeros(1,n);     % columns for each state var; rows for time steps
            v_mat(1,:) = v0_vec';   % row=0; t=0; v = v0 (ICs)
            
            i = 1;
            while v_mat(i,1) < track_end
                k1 = dv_fcn(app,t_vec(i),v_mat(i,:)'); % d{v}/dt 1 (col vector)
                ystar1 = v_mat(i,:)' + (dt/2)*k1;
                
                k2 = dv_fcn(app,t_vec(i) + dt/2, ystar1); % d{v}/dt 2
                ystar2 = v_mat(i,:)' + (dt/2)*k2;
                
                k3 = dv_fcn(app,t_vec(i) + dt/2, ystar2); % d{v}/dt 3
                ystar3 = v_mat(i,:)' + dt*k3;
                
                k4 = dv_fcn(app,t_vec(i) + dt, ystar3); % d{v}/dt 4
                next_v_vec = v_mat(i,:) + dt*(k1' + 2*k2' + 2*k3' + k4')/6;
                
                v_mat = [v_mat; next_v_vec];
                t_vec = [t_vec; t_vec(i) + dt];
                
                i = i + 1;
                
                if t_vec(end) > 100 % don't run longer than 100 seconds
                    break
                end
               
            end
        
        end
        
        
        function dvdt = state_deriv(app,t,v_vec,sq,thetaq)
             g = 32.2;        % ft/s^2
%              mu = 0.01;
%              pair = 0.002377;  % slugs/ft^3
%              Af = (13.5*16)/(144);  % ft^2
%              CD = 0.05;
%              mT = 200/g;
             
             % find current theta value at given s location
             s_curr = v_vec(1);
             [~,idx] = min(abs(sq-s_curr));
             theta_curr = thetaq(idx);       % slope (rad)
             
%              eta = (pair*Af*CD)/(2*mT) + mu/rho_curr;
%              gamma = g*(sin(theta_curr) - mu*cos(theta_curr));
             
             n = size(v_vec,1); % v_vec comes in as column state vector
             dvdt = zeros(n,1); % dvdt will leave as column state vector
             dvdt(1) = v_vec(2);
%              dvdt(2) = gamma - eta*v_vec(2)^2;

             if (app.roadLoadUploaded)
                 p = app.p_coeff;
                 sdot = v_vec(2);    % velocity
                 mass = app.Wmax/g;  % slugs
                 % a = gsin(theta) - FR/m
                 % (extra negative on sin(theta) to account for down slope)
                 % FR = a*v^2 + b*v + c -- road load fit polynomial
                 dvdt(2) = -g*sin(theta_curr) - (p(1)*sdot^2 + p(2)*sdot + p(3))/mass;
             else
                 dvdt(2) = -g*sin(theta_curr); % simplified (no friction) model
             end
        end
        
        % roadload data stored as (milliseconds, rev/s)
        function [] = plotRoadLoad(app)
            time = app.roadLoadData(:,1)/1000;    % [s]
            omega = app.roadLoadData(:,2)*(2*pi); % [rad/s]
            vel = smooth(omega*app.Rw);            % [ft/s] (no-slip)
            
            N_samples = 1000;
            tq = linspace(time(1),time(end),N_samples);
            velq = interp1(time,vel,tq,'pchip');
            at = gradient(velq)./gradient(tq);   % [ft/s^2]
            g = 32.2;                            % [ft/s^2]
            mass = app.Wmax / g;                 % [slugs]
            FR = -mass*at;                       % road load [lbf]
            plot(app.RoadLoadFigure,velq,FR,'-k');
            hold(app.RoadLoadFigure,'on');
            p = polyfit(velq,FR,2); % quadratic fit
            plot(app.RoadLoadFigure,velq,polyval(p,velq),'-r');
            leg = legend(app.RoadLoadFigure,'Experimental','Curve fit');
            set(leg,'Interpreter','latex');
            app.p_coeff = p;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Format Axes
            set(app.HillProfileFigure.Title,'Interpreter','latex');
            app.HillProfileFigure.Title.String = "Race simulation";
            set(app.HillProfileFigure.YLabel,'Interpreter','latex');
            app.HillProfileFigure.YLabel.String = "Height [ft]";
            set(app.HillProfileFigure.XLabel,'Interpreter','latex');
            app.HillProfileFigure.XLabel.String = "Distance [ft]";
            
            set(app.RaceTimeFigure.Title,'Interpreter','latex');
            app.RaceTimeFigure.Title.String = "Race time optimization";
            set(app.RaceTimeFigure.YLabel,'Interpreter','latex');
            app.RaceTimeFigure.YLabel.String = "Total race time [s]";
            set(app.RaceTimeFigure.XLabel,'Interpreter','latex');
            app.RaceTimeFigure.XLabel.String = "Center of gravity location [\% of car length]";
            
            set(app.RoadLoadFigure.Title,'Interpreter','latex');
            app.RoadLoadFigure.Title.String = "Road load curve";
            set(app.RoadLoadFigure.YLabel,'Interpreter','latex');
            app.RoadLoadFigure.YLabel.String = "Road load [lb]";
            set(app.RoadLoadFigure.XLabel,'Interpreter','latex');
            app.RoadLoadFigure.XLabel.String = "Velocity [ft/s]";

            app.FrontAxleFigure.Title.String = " ";
            set(app.FrontAxleFigure.XLabel,'Interpreter','latex');
            app.FrontAxleFigure.XLabel.String = "Distance along track [ft]";
            
            app.RearAxleFigure.Title.String = " ";
            set(app.RearAxleFigure.XLabel,'Interpreter','latex');
            app.RearAxleFigure.XLabel.String = "Distance along track [ft]";
            
            % Set default Edit fields on Data tab
            app.LaEdit.Value = app.La;
            app.LbEdit.Value = app.Lb;
            app.RwEdit.Value = app.Rw;
            app.MwEdit.Value = app.Mw;
            app.LdEdit.Value = app.Ld;
            app.LtEdit.Value = app.Lt;
            
            app.MaEdit.Value = app.Ma;
            app.LaxEdit.Value = app.Lax;
            app.AtEdit.Value = app.At;
            app.StEdit.Value = app.St;
            app.LsEdit.Value = app.Ls;
            
            app.WdEdit.Value = app.Wd;
            app.WcEdit.Value = app.Wc;
            app.WmoveEdit.Value = app.Wmove;
            app.WmaxEdit.Value = app.Wmax;
            
            % Set labels
            app.RaceConditionsLabel.BackgroundColor = app.warnColor;
            app.HillProfileStatus.BackgroundColor = app.warnColor;
            app.RoadLoadStatus.BackgroundColor = app.optionalColor;
            app.HillProfileStatus.Text = "(Required)";
            app.RoadLoadStatus.Text = "(Optional)";
            
            app.CamberThetaLabel.Text = [app.theta, ' = 0 deg'];
            app.CamberDeltaLabel.Text = [app.delta, ' = 0 in'];
            app.ToeThetaLabel.Text = [app.theta, ' = 0 deg'];
            app.ToeDeltaLabel.Text = [app.delta, ' = 0 in'];
            app.CamberThetaLabel2.Text = [app.theta, ' = 0 deg'];
            app.CamberDeltaLabel2.Text = [app.delta, ' = 0 in'];
            app.ToeThetaLabel2.Text = [app.theta, ' = 0 deg'];
            app.ToeDeltaLabel2.Text = [app.delta, ' = 0 in'];
            
            % Reset sim tab
            ResetButtonPushed(app);
        end

        % Button pushed function: HillProfileButton
        function HillProfileButtonPushed(app, event)
            app.HillProfileStatus.Text = "Processing...";
            app.HillProfileStatus.BackgroundColor = app.warnColor;
            [file, path] = uigetfile("*.xlsx");
            app.hillDataFile = [char(path) char(file)];
            
            if (~isfile(app.hillDataFile))
                app.HillProfileStatus.Text = "Must be a valid file!";
                app.HillProfileStatus.BackgroundColor = app.errorColor;
                app.hillProfileUploaded = false;
                return
            end
            
            ext = string(app.hillDataFile).split(".");
            ext = ext(end);
            if (~strcmp(ext,"xlsx"))
                app.HillProfileStatus.Text = "File must be: .xlsx";
                app.HillProfileStatus.BackgroundColor = app.errorColor;
                app.hillProfileUploaded = false;
                return
            end
            
            % upload excel data to workspace
            app.slopeData = xlsread(app.hillDataFile);
            app.HillProfileStatus.Text = "Success!";
            app.HillProfileStatus.BackgroundColor = app.successColor;
            app.RaceConditionsLabel.BackgroundColor = app.defaultColor;
            app.hillProfileUploaded = true;
        end

        % Callback function
        function SoapboxioUIFigureButtonDown(app, event)
            fprintf("Hello\n");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            ResetButtonPushed(app);
            
            if (~app.hillProfileUploaded)
                app.StatusLabel.Text = "Please upload hill profile.";
                app.StatusLamp.Color = app.errorColor;
                return
            end
            
            app.StatusLabel.Text = "Working...";
            app.StatusLamp.Color = app.warnColor;
            
            pause(0.5);
            
            % Plot road load curve
            if (app.roadLoadUploaded)
                app.plotRoadLoad()
            end
            
            % Hill profile
            [hill_points,thetaq] = app.makeHillProfile();
            xq = hill_points(:,1);
            yq = hill_points(:,2);
            sq = hill_points(:,3);
            track_length = sq(end);
            plot(app.HillProfileFigure,xq,yq,'-k','LineWidth',2);
            hold(app.HillProfileFigure,'on');
            
            % Optimize race time
            app.StatusLabel.Text = "Optimizing race time...";
            
            N_samples = 20;
            xcg_sample = linspace(app.La, app.Lb, N_samples);
            tf = zeros(1,N_samples);
            dvdt = @(app,t,v_vec)state_deriv(app,t,v_vec,sq,thetaq);
            dt = 0.01;
            
            for i = 1:N_samples
                xcg = xcg_sample(i);
                v0 = [app.Lb-xcg; 0];
                track_end = track_length - (xcg-app.La);
                [t_vec, ~] = rk4(app,dvdt,v0,dt,track_end); 
                tf(i) = t_vec(end);
            end
            
            % Xcg optimized
            [tf_opt,idx] = min(tf);
            xcg = xcg_sample(idx); % ft
            
            % Animation
            app.StatusLabel.Text = "Running simulation...";
            v0 = [app.Lb-xcg; 0];
            track_end = track_length - (xcg-app.La);
            [t_vec, v_mat] = rk4(app,dvdt,v0,dt,track_end);
            car_len = 50;
            car_height = 0.1*yq(1);
            y_offset = 0; 
            
            i = 1;
            while i < length(t_vec)
                plot(app.HillProfileFigure,xq,yq,'-k','LineWidth',2);
                hold(app.HillProfileFigure,'on');
                
                t_curr = t_vec(i);
                s_curr = v_mat(i,1);
                [~,loc_idx] = min(abs(s_curr - sq));
                x_curr = xq(loc_idx);
                y_curr = yq(loc_idx);
                theta_curr = thetaq(loc_idx);
                
                x_left = x_curr;
                y_bottom = y_curr + y_offset;
                x_plot = [x_left x_left (x_left+car_len) (x_left+car_len) x_left];
                y_plot = [y_bottom (y_bottom+car_height) (y_bottom+car_height) y_bottom y_bottom];
                h = plot(app.HillProfileFigure,x_plot,y_plot,'-g','LineWidth',3);
                rotate(h,[0 0 1],theta_curr*(180/pi),[x_left,y_bottom,0]);
                
                i = i + 20;
                if i < length(t_vec)
                    text_str = sprintf("Time = %5.2f s",t_curr);
                    text(app.HillProfileFigure,xq(end)*0.75,yq(1)*1.85,text_str,'Color','g','FontSize',14,'Interpreter','latex');
                else
                    text_str = sprintf("Time = %5.2f s",t_vec(end));
                    text(app.HillProfileFigure,xq(end)*0.75,yq(1)*1.85,text_str,'Color','g','FontSize',14,'Interpreter','latex');
                end
                hold(app.HillProfileFigure,'off');
                drawnow
            end
            
            pause(1);
            
            % Plot xcg curve
            xcg_pcts = (xcg_sample-app.La)/(app.Lb-app.La);
            plot(app.RaceTimeFigure,xcg_pcts*100,tf,'-g');
            hold(app.RaceTimeFigure,'off');
%             opt_str = sprintf('$x_{cg}=%d\\%%$',round(xcg_opt_pct*100));
%             leg = legend(app.RaceTimeFigure,'$x_{cg}=50\%$',opt_str);
%             set(leg,'Interpreter','latex');
            
            % Calculate lbs nose/tail heavy
            df = round(app.get_weight_dist(xcg));
            
            % Results finished
            app.StatusLabel.Text = "Finishing...";
            
            if (abs(df) < app.dfMax) % check for max weight conditions
                app.poundLabel.Text = sprintf("%3d lb ",abs(df));
            else
                app.poundLabel.Text = sprintf("%2d lb (max)",app.dfMax);
                if (df < 0) 
                    df = -app.dfMax;
                else
                    df = app.dfMax;
                end
                xcg = df*(app.Lb-app.La)/(2*app.Wmax) + (app.La+app.Lb)/2;
            end
            
            xcg_opt_pct = (xcg-app.La)/(app.Lb-app.La);
            app.xcgGauge.Value = xcg_opt_pct*100;
            if (xcg_opt_pct < 0.5) 
                app.TailheavyLamp.Color = app.defaultColor;
                app.NoseheavyLamp.Color = app.successColor;
            else
                app.TailheavyLamp.Color = app.successColor;
                app.NoseheavyLamp.Color = app.defaultColor;
            end
            
            % Move the center of gravity symbol to line up with the gauge
            pos = app.cgSymbol.Position;
            gauge_pos = app.xcgGauge.Position;
            new_x = gauge_pos(1) + 0.02*gauge_pos(3) + xcg_opt_pct*0.95*gauge_pos(3) - (pos(3)/2);
            pos(1) = new_x;
            app.cgSymbol.Position = pos;
            app.cgSymbol.Visible = true;
            
            % Calculate and plot deflections
            la = xcg - app.La; % distance from front axle to xcg
            lb = app.Lb - xcg; % distance from rear axle to xcg
            NB = (app.Wmax*cos(thetaq))/((lb/la)+1); % rear normal force
            NA = (lb/la)*NB; % front normal force
            fB = app.Crr*NB; % friction force on rear wheel
            fA = app.Crr*NA; % friction force on front wheel
            
            % Beam bending
            L = app.Lax - (2*app.Ls); % beam length [in]
            Ix = (1/12)*app.At^4;     % [in^4]
            avg_theta = mean(thetaq); % [rad]
            avg_dist_w = app.Ma/L; % axle distributed weight [lb/in]
            
            camber_theta_front = (mean(NA)*L^2)/(16*app.E*Ix) + (avg_dist_w*cos(avg_theta)*L^3)/(24*app.E*Ix);
            camber_deflection_front = app.Ls*sin(camber_theta_front); 
            toe_theta_front = (mean(fA)*L^2)/(16*app.E*Ix) + (avg_dist_w*sin(avg_theta)*L^3)/(24*app.E*Ix);
            toe_deflection_front = app.Ls*sin(toe_theta_front);
            
            camber_theta_rear = (mean(NB)*L^2)/(16*app.E*Ix) + (avg_dist_w*cos(avg_theta)*L^3)/(24*app.E*Ix);
            camber_deflection_rear = app.Ls*sin(camber_theta_rear); 
            toe_theta_rear = (mean(fB)*L^2)/(16*app.E*Ix) + (avg_dist_w*sin(avg_theta)*L^3)/(24*app.E*Ix);
            toe_deflection_rear = app.Ls*sin(toe_theta_rear);
            
            yyaxis(app.FrontAxleFigure,'left');
            plot(app.FrontAxleFigure,sq,NA);
            ylabel(app.FrontAxleFigure,'Vertical force [lbf]');
            set(app.FrontAxleFigure.YLabel,'Interpreter','latex');
            yyaxis(app.FrontAxleFigure,'right');
            ylabel(app.FrontAxleFigure,'Horizontal force [lbf]');
            set(app.FrontAxleFigure.YLabel,'Interpreter','latex');
            plot(app.FrontAxleFigure,sq,fA);
            
            yyaxis(app.RearAxleFigure,'left');
            plot(app.RearAxleFigure,sq,NB);
            ylabel(app.RearAxleFigure,'Vertical force [lbf]');
            set(app.RearAxleFigure.YLabel,'Interpreter','latex');
            yyaxis(app.RearAxleFigure,'right');
            plot(app.RearAxleFigure,sq,fB);
            ylabel(app.RearAxleFigure,'Horizontal force [lbf]');
            set(app.RearAxleFigure.YLabel,'Interpreter','latex');
            
            app.AyLabel.Text = sprintf('Ay = %.2f lbf',mean(NA));
            app.AxLabel.Text = sprintf('Ax = %.2f lbf',mean(fA));
            app.ByLabel.Text = sprintf('By = %.2f lbf',mean(NB));
            app.BxLabel.Text = sprintf('Bx = %.2f lbf',mean(fB));
            
            app.CamberThetaLabel.Text = sprintf("%s = %.3f deg",app.theta,camber_theta_front*(180/pi));
            app.CamberDeltaLabel.Text = sprintf("%s = %.3f in",app.delta,camber_deflection_front);
            app.ToeThetaLabel.Text = sprintf("%s = %.3f deg",app.theta,toe_theta_front*(180/pi));
            app.ToeDeltaLabel.Text = sprintf("%s = %.3f in",app.delta,toe_deflection_front);
            
            app.CamberThetaLabel2.Text = sprintf("%s = %.3f deg",app.theta,camber_theta_rear*(180/pi));
            app.CamberDeltaLabel2.Text = sprintf("%s = %.3f in",app.delta,camber_deflection_rear);
            app.ToeThetaLabel2.Text = sprintf("%s = %.3f deg",app.theta,toe_theta_rear*(180/pi));
            app.ToeDeltaLabel2.Text = sprintf("%s = %.3f in",app.delta,toe_deflection_rear);
            
            app.StatusLabel.Text = "Finished.";
            app.StatusLamp.Color = app.successColor;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Update parameters
            app.La = app.LaEdit.Value;
            app.Lb = app.LbEdit.Value;
            app.Rw = app.RwEdit.Value;
            app.Mw = app.MwEdit.Value;
            app.Ld = app.LdEdit.Value;
            app.Lt = app.LtEdit.Value;
            
            app.Ma = app.MaEdit.Value;
            app.Lax = app.LaxEdit.Value;
            app.Ls = app.LsEdit.Value;
            app.St = app.StEdit.Value;
            app.At = app.AtEdit.Value;
            
            app.Wd = app.WdEdit.Value;
            app.Wc = app.WcEdit.Value;
            app.Wmove = app.WmoveEdit.Value;
            app.Wmax = app.WmaxEdit.Value;
            
            % Reset Simulation tab
            app.TailheavyLamp.Color = app.defaultColor;
            app.NoseheavyLamp.Color = app.defaultColor;
            app.StatusLamp.Color = app.defaultColor;
            app.xcgGauge.Value = 0;
            app.poundLabel.Text = sprintf("%2d lb ",0);
            app.cgSymbol.Visible = false;
            app.StatusLabel.Text = "Ready.";
            
            % Clear off figures
            cla(app.HillProfileFigure);
            cla(app.RaceTimeFigure);
            cla(app.RoadLoadFigure);
        end

        % Button pushed function: RoadLoadButton
        function RoadLoadButtonPushed(app, event)
            app.RoadLoadStatus.Text = "Processing...";
            app.RoadLoadStatus.BackgroundColor = app.warnColor;
            [file, path] = uigetfile("*.txt");
            app.roadDataFile = [char(path) char(file)];
            
            if (~isfile(app.roadDataFile))
                app.RoadLoadStatus.Text = "Must be a valid file!";
                app.HillProfileStatus.BackgroundColor = app.optionalColor;
                app.roadLoadUploaded = false;
                return
            end
            
            ext = string(app.roadDataFile).split(".");
            ext = ext(end);
            if (~strcmpi(ext,"txt"))
                app.RoadLoadStatus.Text = "File must be: .txt";
                app.RoadLoadStatus.BackgroundColor = app.optionalColor;
                app.roadLoadUploaded = false;
                return
            end
            
            % upload text file road load data to workspace
            data = readtable(app.roadDataFile);
            app.roadLoadData = table2array(data);
            app.RoadLoadStatus.Text = "Success!";
            app.RoadLoadStatus.BackgroundColor = app.successColor;
            app.RaceConditionsLabel.BackgroundColor = app.defaultColor;
            app.roadLoadUploaded = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoapboxioUIFigure = uifigure('Visible', 'off');
            app.SoapboxioUIFigure.Name = 'Soapbox.io';
            app.TabGroup = uitabgroup(app.SoapboxioUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcomeLabel = uilabel(app.HomeTab);
            app.WelcomeLabel.Text = 'Welcome';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'Soapbox isometric.png';
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel = uilabel(app.HomeTab);
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel.Text = {'Authors: Joshua Eckels   (eckelsjd@rose-hulman.edu)   Pascal Liberge (libergpm@rose-hulman.edu)'; '              Charles Earle   (earlecc@rose-hulman.edu)     Shane Saylor   (saylorsa@rose-hulman.edu)'};
            app.SoapboxioLabel = uilabel(app.HomeTab);
            app.SoapboxioLabel.Text = 'Soapbox.io';
            app.Releasev005May2021Label = uilabel(app.HomeTab);
            app.Releasev005May2021Label.Text = 'Release: v.0.0.5 (May 2021)';
            app.AsoftwaretoolforsoapboxderbypreracetuningoptimizationLabel = uilabel(app.HomeTab);
            app.AsoftwaretoolforsoapboxderbypreracetuningoptimizationLabel.Text = {'A software tool for soapbox derby pre-race'; 'tuning optimization.'};
            app.StartLabel = uilabel(app.HomeTab);
            app.StartLabel.Text = 'Start';
            app.NewinthisupdateLabel = uilabel(app.HomeTab);
            app.NewinthisupdateLabel.Text = 'New in this update';
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_2 = uilabel(app.HomeTab);
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_2.Text = 'Compiled for use with the MATLAB (R2020a) 9.8 runtime';
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_3 = uilabel(app.HomeTab);
            app.AuthorsJoshuaEckelseckelsjdrosehulmaneduLabel_3.Text = 'See the latest release documentation.';
            app.PresstheDatatabtogetstartedLabel = uilabel(app.HomeTab);
            app.PresstheDatatabtogetstartedLabel.Text = 'Press the Data tab to get started!';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.CarPanel = uipanel(app.DataTab);
            app.ftLabel = uilabel(app.CarPanel);
            app.ftLabel.Text = 'ft';
            app.FrontaxledistanceEditFieldLabel = uilabel(app.CarPanel);
            app.FrontaxledistanceEditFieldLabel.Text = 'Front axle distance';
            app.LaEdit = uieditfield(app.CarPanel, 'numeric');
            app.ftLabel_2 = uilabel(app.CarPanel);
            app.ftLabel_2.Text = 'ft';
            app.RearaxledistanceEditFieldLabel = uilabel(app.CarPanel);
            app.RearaxledistanceEditFieldLabel.Text = 'Rear axle distance';
            app.LbEdit = uieditfield(app.CarPanel, 'numeric');
            app.ftLabel_5 = uilabel(app.CarPanel);
            app.ftLabel_5.Text = 'ft';
            app.RadiusofwheelEditFieldLabel = uilabel(app.CarPanel);
            app.RadiusofwheelEditFieldLabel.Text = 'Radius of wheel';
            app.RwEdit = uieditfield(app.CarPanel, 'numeric');
            app.lbLabel_4 = uilabel(app.CarPanel);
            app.lbLabel_4.Text = 'lb';
            app.WeightofwheelEditFieldLabel = uilabel(app.CarPanel);
            app.WeightofwheelEditFieldLabel.Text = 'Weight of wheel';
            app.MwEdit = uieditfield(app.CarPanel, 'numeric');
            app.ftLabel_3 = uilabel(app.CarPanel);
            app.ftLabel_3.Text = 'ft';
            app.DriverseatdistanceLabel = uilabel(app.CarPanel);
            app.DriverseatdistanceLabel.Text = 'Driver seat distance';
            app.LdEdit = uieditfield(app.CarPanel, 'numeric');
            app.ftLabel_4 = uilabel(app.CarPanel);
            app.ftLabel_4.Text = 'ft';
            app.OveralllengthLabel = uilabel(app.CarPanel);
            app.OveralllengthLabel.Text = 'Overall length';
            app.LtEdit = uieditfield(app.CarPanel, 'numeric');
            app.mwLabel = uilabel(app.CarPanel);
            app.mwLabel.Text = 'mw';
            app.rwLabel = uilabel(app.CarPanel);
            app.rwLabel.Text = 'rw';
            app.LtLabel = uilabel(app.CarPanel);
            app.LtLabel.Text = 'Lt';
            app.LdLabel = uilabel(app.CarPanel);
            app.LdLabel.Text = 'Ld';
            app.LbLabel = uilabel(app.CarPanel);
            app.LbLabel.Text = 'Lb';
            app.LaLabel = uilabel(app.CarPanel);
            app.LaLabel.Text = 'La';
            app.CardimensionsLabel = uilabel(app.DataTab);
            app.CardimensionsLabel.Text = 'Car dimensions';
            app.RequiredPanel = uipanel(app.DataTab);
            app.lbLabel_5 = uilabel(app.RequiredPanel);
            app.lbLabel_5.Text = 'lb';
            app.WeightofdriverEditField_3Label = uilabel(app.RequiredPanel);
            app.WeightofdriverEditField_3Label.Text = 'Weight of driver';
            app.WdEdit = uieditfield(app.RequiredPanel, 'numeric');
            app.lbLabel_6 = uilabel(app.RequiredPanel);
            app.lbLabel_6.Text = 'lb';
            app.WeightofemptycarnodriverLabel = uilabel(app.RequiredPanel);
            app.WeightofemptycarnodriverLabel.Text = 'Weight of empty car (no driver)';
            app.WcEdit = uieditfield(app.RequiredPanel, 'numeric');
            app.HillProfileButton = uibutton(app.RequiredPanel, 'push');
            app.HillProfileButton.ButtonPushedFcn = createCallbackFcn(app, @HillProfileButtonPushed, true);
            app.HillProfileButton.Text = {'Upload'; ''};
            app.HillprofiledataLabel_2 = uilabel(app.RequiredPanel);
            app.HillprofiledataLabel_2.Text = 'Hill profile data';
            app.MaximumraceweightofcarLabel = uilabel(app.RequiredPanel);
            app.MaximumraceweightofcarLabel.Text = 'Maximum race weight of car';
            app.WmaxEdit = uieditfield(app.RequiredPanel, 'numeric');
            app.lbLabel_7 = uilabel(app.RequiredPanel);
            app.lbLabel_7.Text = 'lb';
            app.TotalmoveableweightLabel = uilabel(app.RequiredPanel);
            app.TotalmoveableweightLabel.Text = 'Total moveable weight';
            app.WmoveEdit = uieditfield(app.RequiredPanel, 'numeric');
            app.lbLabel_8 = uilabel(app.RequiredPanel);
            app.lbLabel_8.Text = 'lb';
            app.HillProfileStatus = uilabel(app.RequiredPanel);
            app.HillProfileStatus.Text = {'(Required)'; ''};
            app.RoadLoadButton = uibutton(app.RequiredPanel, 'push');
            app.RoadLoadButton.ButtonPushedFcn = createCallbackFcn(app, @RoadLoadButtonPushed, true);
            app.RoadLoadButton.Text = {'Upload'; ''};
            app.RoadloaddataLabel = uilabel(app.RequiredPanel);
            app.RoadloaddataLabel.Text = 'Road load data';
            app.RoadLoadStatus = uilabel(app.RequiredPanel);
            app.RoadLoadStatus.Text = '(Optional)';
            app.RaceConditionsLabel = uilabel(app.DataTab);
            app.RaceConditionsLabel.Text = 'Race conditions';
            app.Image3 = uiimage(app.DataTab);
            app.Image3.ImageSource = 'side.png';
            app.AxlePanel = uipanel(app.DataTab);
            app.lbLabel_9 = uilabel(app.AxlePanel);
            app.lbLabel_9.Text = 'lb';
            app.WeightofaxleLabel = uilabel(app.AxlePanel);
            app.WeightofaxleLabel.Text = 'Weight of axle';
            app.MaEdit = uieditfield(app.AxlePanel, 'numeric');
            app.inLabel_5 = uilabel(app.AxlePanel);
            app.inLabel_5.Text = 'in';
            app.LengthofaxleLabel = uilabel(app.AxlePanel);
            app.LengthofaxleLabel.Text = 'Length of axle';
            app.LaxEdit = uieditfield(app.AxlePanel, 'numeric');
            app.inLabel_2 = uilabel(app.AxlePanel);
            app.inLabel_2.Text = 'in';
            app.AxlethicknessLabel = uilabel(app.AxlePanel);
            app.AxlethicknessLabel.Text = 'Axle thickness';
            app.AtEdit = uieditfield(app.AxlePanel, 'numeric');
            app.inLabel_3 = uilabel(app.AxlePanel);
            app.inLabel_3.Text = 'in';
            app.LengthofspindleLabel = uilabel(app.AxlePanel);
            app.LengthofspindleLabel.Text = 'Length of spindle';
            app.LsEdit = uieditfield(app.AxlePanel, 'numeric');
            app.inLabel_4 = uilabel(app.AxlePanel);
            app.inLabel_4.Text = 'in';
            app.SpindlethicknessLabel = uilabel(app.AxlePanel);
            app.SpindlethicknessLabel.Text = 'Spindle thickness';
            app.StEdit = uieditfield(app.AxlePanel, 'numeric');
            app.AtLabel = uilabel(app.AxlePanel);
            app.AtLabel.Text = 'At';
            app.StLabel = uilabel(app.AxlePanel);
            app.StLabel.Text = 'St';
            app.LsLabel = uilabel(app.AxlePanel);
            app.LsLabel.Text = 'Ls';
            app.LaxLabel = uilabel(app.AxlePanel);
            app.LaxLabel.Text = 'Lax';
            app.maLabel = uilabel(app.AxlePanel);
            app.maLabel.Text = 'ma';
            app.AxledimensionsLabel = uilabel(app.DataTab);
            app.AxledimensionsLabel.Text = 'Axle dimensions';
            app.Image5 = uiimage(app.DataTab);
            app.Image5.ImageSource = 'axle figure.png';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimResultsPanel = uipanel(app.SimulationTab);
            app.Image4 = uiimage(app.SimResultsPanel);
            app.Image4.ImageSource = 'xcg side 6.png';
            app.Label = uilabel(app.SimResultsPanel);
            app.Label.Text = '(%)';
            app.xcgGauge = uigauge(app.SimResultsPanel, 'linear');
            app.xcgGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TailheavyLampLabel = uilabel(app.SimResultsPanel);
            app.TailheavyLampLabel.Text = 'Tail heavy';
            app.TailheavyLamp = uilamp(app.SimResultsPanel);
            app.NoseheavyLampLabel = uilabel(app.SimResultsPanel);
            app.NoseheavyLampLabel.Text = 'Nose heavy';
            app.NoseheavyLamp = uilamp(app.SimResultsPanel);
            app.poundLabel = uilabel(app.SimResultsPanel);
            app.poundLabel.Text = '10 lb ';
            app.cgSymbol = uiimage(app.SimResultsPanel);
            app.cgSymbol.ImageSource = 'cg_symbol_line.png';
            app.poundLabel_2 = uilabel(app.SimResultsPanel);
            app.poundLabel_2.Text = 'front';
            app.poundLabel_3 = uilabel(app.SimResultsPanel);
            app.poundLabel_3.Text = 'back';
            app.ResultsLabel = uilabel(app.SimulationTab);
            app.ResultsLabel.Text = 'Results';
            app.StatusPanel = uipanel(app.SimulationTab);
            app.StartButton = uibutton(app.StatusPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.StatusPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Ready.';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusLabelTitle = uilabel(app.SimulationTab);
            app.StatusLabelTitle.Text = 'Status';
            app.HillProfileFigure = uiaxes(app.SimulationTab);
            title(app.HillProfileFigure, 'Title')
            xlabel(app.HillProfileFigure, 'X')
            ylabel(app.HillProfileFigure, 'Y')
            app.RaceTimeFigure = uiaxes(app.SimulationTab);
            title(app.RaceTimeFigure, 'Title')
            xlabel(app.RaceTimeFigure, 'X')
            ylabel(app.RaceTimeFigure, 'Y')
            app.RoadLoadFigure = uiaxes(app.SimulationTab);
            title(app.RoadLoadFigure, 'Title')
            xlabel(app.RoadLoadFigure, 'X')
            ylabel(app.RoadLoadFigure, 'Y')
            app.SpindlesTab = uitab(app.TabGroup);
            app.SpindlesTab.Title = 'Spindles';
            app.FrontAxlePanel = uipanel(app.SpindlesTab);
            app.Image6 = uiimage(app.FrontAxlePanel);
            app.Image6.ImageSource = 'front_wheel.png';
            app.Image7 = uiimage(app.FrontAxlePanel);
            app.Image7.ImageSource = 'front_axle.png';
            app.FrontAxleFigure = uiaxes(app.FrontAxlePanel);
            title(app.FrontAxleFigure, 'Title')
            xlabel(app.FrontAxleFigure, 'X')
            ylabel(app.FrontAxleFigure, 'Y')
            app.AverageforcesLabel = uilabel(app.FrontAxlePanel);
            app.AverageforcesLabel.Text = 'Average forces:';
            app.AxLabel = uilabel(app.FrontAxlePanel);
            app.AxLabel.Text = 'Ax:  ';
            app.AyLabel = uilabel(app.FrontAxlePanel);
            app.AyLabel.Text = 'Ay: ';
            app.CamberyLabel = uilabel(app.FrontAxlePanel);
            app.CamberyLabel.Text = 'Camber (y):';
            app.CamberThetaLabel = uilabel(app.FrontAxlePanel);
            app.CamberThetaLabel.Text = 'Ax:  ';
            app.CamberDeltaLabel = uilabel(app.FrontAxlePanel);
            app.CamberDeltaLabel.Text = 'Ay: ';
            app.ToexLabel = uilabel(app.FrontAxlePanel);
            app.ToexLabel.Text = 'Toe (x):';
            app.ToeThetaLabel = uilabel(app.FrontAxlePanel);
            app.ToeThetaLabel.Text = 'Ax:  ';
            app.ToeDeltaLabel = uilabel(app.FrontAxlePanel);
            app.ToeDeltaLabel.Text = 'Ay: ';
            app.FrontaxleLabel = uilabel(app.SpindlesTab);
            app.FrontaxleLabel.Text = 'Front axle';
            app.RearAxlePanel = uipanel(app.SpindlesTab);
            app.Image6_2 = uiimage(app.RearAxlePanel);
            app.Image6_2.ImageSource = 'rear_wheel.png';
            app.Image7_2 = uiimage(app.RearAxlePanel);
            app.Image7_2.ImageSource = 'rear_axle.png';
            app.RearAxleFigure = uiaxes(app.RearAxlePanel);
            title(app.RearAxleFigure, 'Title')
            xlabel(app.RearAxleFigure, 'X')
            ylabel(app.RearAxleFigure, 'Y')
            app.AverageforcesLabel_2 = uilabel(app.RearAxlePanel);
            app.AverageforcesLabel_2.Text = 'Average forces:';
            app.BxLabel = uilabel(app.RearAxlePanel);
            app.BxLabel.Text = 'Bx:  ';
            app.ByLabel = uilabel(app.RearAxlePanel);
            app.ByLabel.Text = 'By: ';
            app.CamberyLabel_2 = uilabel(app.RearAxlePanel);
            app.CamberyLabel_2.Text = 'Camber (y):';
            app.CamberThetaLabel2 = uilabel(app.RearAxlePanel);
            app.CamberThetaLabel2.Text = 'Ax:  ';
            app.CamberDeltaLabel2 = uilabel(app.RearAxlePanel);
            app.CamberDeltaLabel2.Text = 'Ay: ';
            app.ToexLabel_2 = uilabel(app.RearAxlePanel);
            app.ToexLabel_2.Text = 'Toe (x):';
            app.ToeThetaLabel2 = uilabel(app.RearAxlePanel);
            app.ToeThetaLabel2.Text = 'Ax:  ';
            app.ToeDeltaLabel2 = uilabel(app.RearAxlePanel);
            app.ToeDeltaLabel2.Text = 'Ay: ';
            app.RearaxleLabel = uilabel(app.SpindlesTab);
            app.RearaxleLabel.Text = 'Rear axle';
            % Show the figure after all components are created
            app.SoapboxioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoapboxTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoapboxioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoapboxioUIFigure)
        end
    end
end